classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label                      matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        SensorInputEditField       matlab.ui.control.NumericEditField
        SensorInputEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SensorInputEditField
        function SensorInputEditFieldValueChanged(app, event)
            value = app.SensorInputEditField.Value;
            
            % input affects label and lamp
            
            % switch statements
                % vs IF: if for conditionals
                % 0 Inactive
                % 1 Active
                % 2 Error
            switch value
                case 0
                    app.Lamp.Color = [0.80,0.80,0.80]; %Grey
                    app.Label.Text = 'Inactive';

                case 1
                    app.Lamp.Color = [0 1 0]; %Green
                    app.Label.Text = 'Active';

                case 2
                    app.Lamp.Color = [1 0 0]; %Red
                    app.Label.Text = 'Error';

                otherwise
                    app.Lamp.Color = [0 0 0];
                    app.Label.Text = 'Unknown';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SensorInputEditFieldLabel = uilabel(app.UIFigure);
            app.SensorInputEditFieldLabel.Text = 'Sensor Input';
            app.SensorInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.SensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @SensorInputEditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end