classdef Snatch_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SettingsPanel     matlab.ui.container.Panel
        LengthSpinner     matlab.ui.control.Spinner
        LengthLabel       matlab.ui.control.Label
        ModeLabel         matlab.ui.control.Label
        ModeDD            matlab.ui.control.DropDown
        SettingsLabel     matlab.ui.control.Label
        HoardPanel        matlab.ui.container.Panel
        NewWordDD         matlab.ui.control.DropDown
        NewTileButton     matlab.ui.control.Button
        NewWordEditField  matlab.ui.control.EditField
        ValidWordLamp     matlab.ui.control.Lamp
        HoardLabel        matlab.ui.control.Label
        NEWGAMEButton     matlab.ui.control.Button
        PoolPanel         matlab.ui.container.Panel
        PoolLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModeDD
        function ModeDDValueChanged(app, event)
            % Get selected game mode
            selectedSize = app.ModeDD.Value;
    

            % Define letter counts for each game mode
            switch selectedSize
                case 'Default' 
                    % 144 tiles
                    letter_count = struct('E',18, 'A',13, 'I',12, 'O',11, 'T',9, 'R',9, ...
                        'N',8, 'D',6, 'S',6, 'U',6, 'L',5, 'G',4, 'B',3, 'C',3, 'F',3, ...
                        'H',3, 'M',3, 'P',3, 'V',3, 'W',3, 'Y',3, 'J',2, 'K',2, 'Q',2, ...
                        'X',2, 'Z',2);
        
                case 'Mini'
                    % 78 tiles
                    letter_count = struct('E',9, 'A',7, 'I',6, 'O',5, 'T',5, 'R',5, ...
                        'N',4, 'D',3, 'S',3, 'U',3, 'L',3, 'G',2, 'B',2, 'C',2, 'F',2, ...
                        'H',2, 'M',2, 'P',2, 'V',2, 'W',2, 'Y',2, 'J',1, 'K',1, 'Q',1, ...
                        'X',1, 'Z',1);
        
                case 'Wild'
                    % 144 randomized tiles FIX THIS
                    letter_count = struct('E',20, 'A',15, 'I',15, 'O',14, 'T',12, 'R',12, ...
                        'N',10, 'D',8, 'S',8, 'U',8, 'L',7, 'G',6, 'B',5, 'C',5, 'F',5, ...
                        'H',5, 'M',5, 'P',5, 'V',4, 'W',4, 'Y',4, 'J',3, 'K',3, 'Q',3, ...
                        'X',3, 'Z',3);
        
                case 'X-Small'
                    % 8 tiles, for testing purposes
                    letter_count = struct('E',2, 'A',2, 'T',1, 'R',1, 'D',1, 'S',1);
        
                otherwise
                    error('Unknown tile bag size selected');
            end


            % Display the selected game mode
            app.Label.Text = ['Selected Game Mode: ', selectedSize];

            % Create the tile bag based on the letter count
            app.tileBag = createTileBag(letter_count);
        end


        % Helper function to create a tile bag from letter_count
        function tileBag = createTileBag(letter_count)
            letters = fieldnames(letter_count);
            tileBag = {};
            for i = 1:numel(letters)
                letter = letters{i};
                count = letter_count.(letter);
                tileBag = [tileBag, repmat({letter}, 1, count)];
            end
       

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PoolPanel = uipanel(app.UIFigure);
            app.PoolLabel = uilabel(app.PoolPanel);
            app.PoolLabel.Text = 'POOL';
            app.NEWGAMEButton = uibutton(app.UIFigure, 'push');
            app.NEWGAMEButton.Text = 'NEW GAME';
            app.HoardPanel = uipanel(app.UIFigure);
            app.HoardLabel = uilabel(app.HoardPanel);
            app.HoardLabel.Text = 'HOARD';
            app.ValidWordLamp = uilamp(app.HoardPanel);
            app.NewWordEditField = uieditfield(app.HoardPanel, 'text');
            app.NewTileButton = uibutton(app.HoardPanel, 'push');
            app.NewTileButton.Text = 'NEW TILE';
            app.NewWordDD = uidropdown(app.HoardPanel);
            app.NewWordDD.Items = {'NEW WORD', 'STEAL'};
            app.NewWordDD.Value = 'STEAL';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsLabel = uilabel(app.SettingsPanel);
            app.SettingsLabel.Text = 'SETTINGS';
            app.ModeDD = uidropdown(app.SettingsPanel);
            app.ModeDD.Items = {'Default', 'Mini', 'Wild'};
            app.ModeDD.ValueChangedFcn = createCallbackFcn(app, @ModeDDValueChanged, true);
            app.ModeDD.Value = 'Default';
            app.ModeLabel = uilabel(app.SettingsPanel);
            app.ModeLabel.Text = 'GAME MODE';
            app.LengthLabel = uilabel(app.SettingsPanel);
            app.LengthLabel.Text = 'MINIMUM LENGTH';
            app.LengthSpinner = uispinner(app.SettingsPanel);
            app.LengthSpinner.Limits = [3 7];
            app.LengthSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Snatch_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end